classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TimeDomainTab                   matlab.ui.container.Tab
        FolderEditField_2Label          matlab.ui.control.Label
        folder_dir                      matlab.ui.control.EditField
        choose_dir                      matlab.ui.control.Button
        FilenameFormatEditFieldLabel    matlab.ui.control.Label
        FilenameFormatEditField         matlab.ui.control.EditField
        egTXistransmitterRXisreceiverciscountersissuffixLabel  matlab.ui.control.Label
        SaveOptionsLabel                matlab.ui.control.Label
        TxAntennaLabel_Matrix           matlab.ui.control.Label
        RxAntennaLabel_Matrix           matlab.ui.control.Label
        AntennaLabel_1                  matlab.ui.control.Label
        AntennaSpinner_1                matlab.ui.control.Spinner
        SwitchingMatrixLabel            matlab.ui.control.Label
        SetButton_1                     matlab.ui.control.Button
        SetButton_2                     matlab.ui.control.Button
        Antenna_Label2                  matlab.ui.control.Label
        AntennaSpinner_2                matlab.ui.control.Spinner
        Switch_2                        matlab.ui.control.Switch
        NbSamples_Label                 matlab.ui.control.Label
        NbSamples_Spinner               matlab.ui.control.Spinner
        StartSample_Label               matlab.ui.control.Label
        StartSample_Spinner             matlab.ui.control.Spinner
        EndSample_Label                 matlab.ui.control.Label
        EndSample_Spinner               matlab.ui.control.Spinner
        ProgressEditFieldLabel          matlab.ui.control.Label
        ProgressEditField               matlab.ui.control.NumericEditField
        percent_Label                   matlab.ui.control.Label
        PS9201ATab                      matlab.ui.container.Tab
        PS9201ALabel                    matlab.ui.control.Label
        ConnectButton                   matlab.ui.control.Button
        ConfigureButton                 matlab.ui.control.Button
        ConfigFileButton                matlab.ui.control.Button
        ConfigurationFileEditFieldLabel  matlab.ui.control.Label
        ConfigurationFileEditField      matlab.ui.control.EditField
        SampleNumberEditFieldLabel      matlab.ui.control.Label
        SampleNumberEditField           matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel      matlab.ui.control.Label
        SamplingRateEditField           matlab.ui.control.NumericEditField
        AverageNumberEditFieldLabel     matlab.ui.control.Label
        AverageNumberEditField          matlab.ui.control.NumericEditField
        GridScalingmVdivEditFieldLabel  matlab.ui.control.Label
        GridScalingmVdivEditField       matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: choose_dir
        function choose_dirPushed(app, event)
            % Choose location of text file to save data in
            %selpath = uigetdir('P:\'); figure(app.UIFigure);
            selpath = uigetdir("P:\","Choose Folder");
            %setting "figure" to UIFigure because for some reason if this is not done the window is minimized when you press cancel
            
            %If user clicks on cancel, stop operation
            if selpath==0
                return;
            else
               %Changes the text value to according path
               app.folder_dir.Value = selpath;
            end
           
        end

        % Value changed function: folder_dir
        function folder_dirValueChanged(app, event)
            value = app.folder_dir.Value;
            
        end

        % Value changing function: folder_dir
        function folder_dirValueChanging(app, event)
            changingValue = event.Value;
          
            
        end

        % Button pushed function: ConfigFileButton
        function ConfigFileButtonPushed(app, event)
            %get file needed
            [file,path]= uigetfile('*.txt','Select File'); figure(app.UIFigure);
            %setting "figure" to UIFigure because for some reason if this is not done the window is minimized when you press cancel
               
            
            %Read data from selected text file
            %s={};
            %file=fullfile(PathName,FileName)
            %fid = fopen(file); 
            %tline = fgetl(fid); 
            %while ischar(tline) 
               %s=[s;tline]; 
               %tline = fgetl(fid); 
            %end    
            app.ConfigurationFileEditField.Value = strcat(path,file);
        end

        % Callback function: ConfigurationFileEditField, 
        % ConfigurationFileEditField
        function ConfigurationFileEditFieldValueChanging(app, event)
          
        end

        % Button pushed function: ConfigureButton
        function ConfigureButtonPushed(app, event)
            jBarHandle = javax.swing.JProgressBar(0, 103);
            jBarHandle.setStringPainted(true);
            jBarHandle.setIndeterminate(false);
           
            fig = figure('Position', [0 0 200 20]);
            [jhandle, hhandle] = javacomponent(jBarHandle, [0 0 1 1], fig);
            set(hhandle, 'parent', fig, 'Units', 'norm', 'Position', [0 0 1 1])
            
            for k = 1:103
                javaMethodEDT('setValue', jBarHandle, k);
                pause(0.1);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimeDomainTab = uitab(app.TabGroup);
            app.TimeDomainTab.Title = 'Time Domain';
            app.FolderEditField_2Label = uilabel(app.TimeDomainTab);
            app.FolderEditField_2Label.Text = 'Folder:';
            app.folder_dir = uieditfield(app.TimeDomainTab, 'text');
            app.folder_dir.ValueChangedFcn = createCallbackFcn(app, @folder_dirValueChanged, true);
            app.folder_dir.ValueChangingFcn = createCallbackFcn(app, @folder_dirValueChanging, true);
            app.folder_dir.Value = 'C:\Users\Experiments\Desktop\Temporary';
            app.choose_dir = uibutton(app.TimeDomainTab, 'push');
            app.choose_dir.ButtonPushedFcn = createCallbackFcn(app, @choose_dirPushed, true);
            app.choose_dir.Text = '...';
            app.FilenameFormatEditFieldLabel = uilabel(app.TimeDomainTab);
            app.FilenameFormatEditFieldLabel.Text = 'File name Format';
            app.FilenameFormatEditField = uieditfield(app.TimeDomainTab, 'text');
            app.FilenameFormatEditField.Value = 'sig_%TX_%RX%.txt';
            app.egTXistransmitterRXisreceiverciscountersissuffixLabel = uilabel(app.TimeDomainTab);
            app.egTXistransmitterRXisreceiverciscountersissuffixLabel.Text = 'e.g. %TX% is transmitter, %RX% is receiver// %c% is counter, %s% is suffix';
            app.SaveOptionsLabel = uilabel(app.TimeDomainTab);
            app.SaveOptionsLabel.Text = 'Save Options';
            app.ProgressEditFieldLabel = uilabel(app.TimeDomainTab);
            app.ProgressEditFieldLabel.Text = 'Progress';
            app.ProgressEditField = uieditfield(app.TimeDomainTab, 'numeric');
            app.percent_Label = uilabel(app.TimeDomainTab);
            app.percent_Label.Text = '%';
            app.TxAntennaLabel_Matrix = uilabel(app.TimeDomainTab);
            app.TxAntennaLabel_Matrix.Text = 'Tx Antenna';
            app.RxAntennaLabel_Matrix = uilabel(app.TimeDomainTab);
            app.RxAntennaLabel_Matrix.Text = 'Rx Antenna';
            app.AntennaLabel_1 = uilabel(app.TimeDomainTab);
            app.AntennaLabel_1.Text = 'Antenna #:';
            app.AntennaSpinner_1 = uispinner(app.TimeDomainTab);
            app.AntennaSpinner_1.Value = 1;
            app.SwitchingMatrixLabel = uilabel(app.TimeDomainTab);
            app.SwitchingMatrixLabel.Text = 'Switching Matrix';
            app.SetButton_1 = uibutton(app.TimeDomainTab, 'push');
            app.SetButton_1.Text = 'Set';
            app.SetButton_2 = uibutton(app.TimeDomainTab, 'push');
            app.SetButton_2.Text = 'Set';
            app.Antenna_Label2 = uilabel(app.TimeDomainTab);
            app.Antenna_Label2.Text = 'Antenna #:';
            app.AntennaSpinner_2 = uispinner(app.TimeDomainTab);
            app.AntennaSpinner_2.Value = 1;
            app.Switch_2 = uiswitch(app.TimeDomainTab, 'slider');
            app.NbSamples_Label = uilabel(app.TimeDomainTab);
            app.NbSamples_Label.Text = 'Number of Samples';
            app.NbSamples_Spinner = uispinner(app.TimeDomainTab);
            app.NbSamples_Spinner.Value = 1;
            app.StartSample_Label = uilabel(app.TimeDomainTab);
            app.StartSample_Label.Text = 'Starts at Sample #';
            app.StartSample_Spinner = uispinner(app.TimeDomainTab);
            app.StartSample_Spinner.Value = 1;
            app.EndSample_Label = uilabel(app.TimeDomainTab);
            app.EndSample_Label.Text = 'Ends at Sample #';
            app.EndSample_Spinner = uispinner(app.TimeDomainTab);
            app.EndSample_Spinner.Value = 1;
            app.PS9201ATab = uitab(app.TabGroup);
            app.PS9201ATab.Title = 'PS9201A';
            app.PS9201ALabel = uilabel(app.PS9201ATab);
            app.PS9201ALabel.Text = 'PS9201A';
            app.ConnectButton = uibutton(app.PS9201ATab, 'push');
            app.ConnectButton.Text = 'Connect';
            app.ConfigureButton = uibutton(app.PS9201ATab, 'push');
            app.ConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureButtonPushed, true);
            app.ConfigureButton.Text = 'Configure';
            app.ConfigFileButton = uibutton(app.PS9201ATab, 'push');
            app.ConfigFileButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigFileButtonPushed, true);
            app.ConfigFileButton.Text = '...';
            app.ConfigurationFileEditFieldLabel = uilabel(app.PS9201ATab);
            app.ConfigurationFileEditFieldLabel.Text = 'Configuration File';
            app.ConfigurationFileEditField = uieditfield(app.PS9201ATab, 'text');
            app.ConfigurationFileEditField.ValueChangedFcn = createCallbackFcn(app, @ConfigurationFileEditFieldValueChanging, true);
            app.ConfigurationFileEditField.ValueChangingFcn = createCallbackFcn(app, @ConfigurationFileEditFieldValueChanging, true);
            app.ConfigurationFileEditField.Value = 'C:\Users\Experiments\Desktop\Temporary';
            app.SampleNumberEditFieldLabel = uilabel(app.PS9201ATab);
            app.SampleNumberEditFieldLabel.Text = 'Sample Number';
            app.SampleNumberEditField = uieditfield(app.PS9201ATab, 'numeric');
            app.SamplingRateEditFieldLabel = uilabel(app.PS9201ATab);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.PS9201ATab, 'numeric');
            app.AverageNumberEditFieldLabel = uilabel(app.PS9201ATab);
            app.AverageNumberEditFieldLabel.Text = 'Average Number';
            app.AverageNumberEditField = uieditfield(app.PS9201ATab, 'numeric');
            app.GridScalingmVdivEditFieldLabel = uilabel(app.PS9201ATab);
            app.GridScalingmVdivEditFieldLabel.Text = 'Grid Scaling (mV/div)';
            app.GridScalingmVdivEditField = uieditfield(app.PS9201ATab, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end